classdef visualizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Visualizer                matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        PlayButton                matlab.ui.control.Button
        ScenarioDropDownLabel     matlab.ui.control.Label
        ScenarioDropdown          matlab.ui.control.DropDown
        TransmitterDropDownLabel  matlab.ui.control.Label
        TxDropdown                matlab.ui.control.DropDown
        ReceiverDropDownLabel     matlab.ui.control.Label
        RxDropdown                matlab.ui.control.DropDown
        TimestepSliderLabel       matlab.ui.control.Label
        TimestepSlider            matlab.ui.control.Slider
        PauseButton               matlab.ui.control.Button
        TimestepSpinner           matlab.ui.control.Spinner
        AodAxes                   matlab.ui.control.UIAxes
        AoaAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        scenarioName % name of the selected scenario
        
        timestepInfo
        
        outputPath
        visualizerPath
        ns3Path
        
        numNodes
        txIndex % starting from 1
        rxIndex % starting from 1
        
        roomPlotHandle
        raysPlotHandle
        nodesPlotHandle
        aoaPlotHandle
        aodPlotHandle
        
        framerate = 20;
        playFlag = false;
        
        rayColors = [...
            0,0,0;...
            1,0,0;...
            0,1,0;...
            0,0,1];
        rayWidth = [3,1,0.5];
        powerLim
    end
    
    properties (SetObservable)
          currentTimestep
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            close all
            clc
            addpath('raysAppFunctions', '../utils/')
            
            % Init
            app.Visualizer.Name = 'Visualizer';
            app.AoaAxes.Title.String = 'AoA';
            app.AodAxes.Title.String = 'AoD';
%             app.ScenarioDropdown.Items = {'Indoor1',...
%                 'Indoor2',...
%                 'DenserScenario',...
%                 'SpatialSharing',...
%                 'L-Room',...
%                 'Outdoor1'}; % todo: make a fnc that reads the list of possible scenarios
            inFolderList = dir("../statsScenarios/L-Room");
            inFolderList(1:2) = [];
            tmp = [inFolderList.isdir];
            folders = extractfield(inFolderList,"name");
            folders = folders(tmp);
            
            app.ScenarioDropdown.Items = folders;
            
            setupAoaAodPlots(app)
            
            % Properties setup
            app.TimestepSpinner.RoundFractionalValues = 'on';
            
            addlistener(app,'currentTimestep','PostSet',@updateTimestep);
                        
            updateScenario(app)
        end

        % Value changed function: ScenarioDropdown
        function ScenarioDropdownValueChanged(app, event)
            updateScenario(app)
            raysAppPlotRoom(app)            
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            app.playFlag = true;
            playScenario(app, event)
        end

        % Value changed function: TxDropdown
        function TxDropdownValueChanged(app, event)
            value = app.TxDropdown.Value;
            setupTxSlider(app, str2double(value)+1)
            plotFrame(app)
        end

        % Value changed function: RxDropdown
        function RxDropdownValueChanged(app, event)
            value = app.RxDropdown.Value;
            setupRxSlider(app, str2double(value)+1)
            plotFrame(app)
        end

        % Value changed function: TimestepSlider
        function TimestepSliderValueChanged(app, event)
            value = app.TimestepSlider.Value;
            
        end

        % Callback function
        function TimeStepFieldValueChanged(app, event)
            
        end

        % Value changing function: TimestepSlider
        function TimestepSliderValueChanging(app, event)
            app.playFlag = false;
            roundedValue = round(event.Value);
            
            if roundedValue ~= app.currentTimestep
                app.currentTimestep = roundedValue;
            end
        end

        % Value changed function: TimestepSpinner
        function TimestepSpinnerValueChanged(app, event)
            value = app.TimestepSpinner.Value;
            
        end

        % Value changing function: TimestepSpinner
        function TimestepSpinnerValueChanging(app, event)
            app.playFlag = false;
            changingValue = event.Value;
            app.currentTimestep = changingValue;
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            app.playFlag = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Visualizer = uifigure('Visible', 'off');
            app.Visualizer.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.Visualizer);
            title(app.UIAxes, '', 'Interpreter', 'latex')
            xlabel(app.UIAxes, '', 'Interpreter', 'latex')
            ylabel(app.UIAxes, '', 'Interpreter', 'latex')
            app.PlayButton = uibutton(app.Visualizer, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.ScenarioDropDownLabel = uilabel(app.Visualizer);
            app.ScenarioDropDownLabel.Text = 'Scenario';
            app.ScenarioDropdown = uidropdown(app.Visualizer);
            app.ScenarioDropdown.ValueChangedFcn = createCallbackFcn(app, @ScenarioDropdownValueChanged, true);
            app.TransmitterDropDownLabel = uilabel(app.Visualizer);
            app.TransmitterDropDownLabel.Text = 'Transmitter';
            app.TxDropdown = uidropdown(app.Visualizer);
            app.TxDropdown.ValueChangedFcn = createCallbackFcn(app, @TxDropdownValueChanged, true);
            app.ReceiverDropDownLabel = uilabel(app.Visualizer);
            app.ReceiverDropDownLabel.Text = 'Receiver';
            app.RxDropdown = uidropdown(app.Visualizer);
            app.RxDropdown.ValueChangedFcn = createCallbackFcn(app, @RxDropdownValueChanged, true);
            app.TimestepSliderLabel = uilabel(app.Visualizer);
            app.TimestepSliderLabel.Text = 'Time-step';
            app.TimestepSlider = uislider(app.Visualizer);
            app.TimestepSlider.ValueChangedFcn = createCallbackFcn(app, @TimestepSliderValueChanged, true);
            app.TimestepSlider.ValueChangingFcn = createCallbackFcn(app, @TimestepSliderValueChanging, true);
            app.PauseButton = uibutton(app.Visualizer, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.TimestepSpinner = uispinner(app.Visualizer);
            app.TimestepSpinner.ValueChangingFcn = createCallbackFcn(app, @TimestepSpinnerValueChanging, true);
            app.TimestepSpinner.ValueChangedFcn = createCallbackFcn(app, @TimestepSpinnerValueChanged, true);
            app.AodAxes = uiaxes(app.Visualizer);
            title(app.AodAxes, '', 'Interpreter', 'latex')
            xlabel(app.AodAxes, '', 'Interpreter', 'latex')
            ylabel(app.AodAxes, '', 'Interpreter', 'latex')
            app.AoaAxes = uiaxes(app.Visualizer);
            title(app.AoaAxes, '', 'Interpreter', 'latex')
            xlabel(app.AoaAxes, '', 'Interpreter', 'latex')
            ylabel(app.AoaAxes, '', 'Interpreter', 'latex')
            % Show the figure after all components are created
            app.Visualizer.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = visualizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Visualizer)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Visualizer)
        end
    end
end